classdef AtrialECG_V1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AtrialECGUIFigure            matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenProcessedECGsMenu        matlab.ui.container.Menu
        SaveProcessedECGsMenu        matlab.ui.container.Menu
        KLabel                       matlab.ui.control.Label
        SaveFigsCheckBox             matlab.ui.control.CheckBox
        OneAndHalfGainButton         matlab.ui.control.Button
        ThreeQuartersGainButton      matlab.ui.control.Button
        SaveECGsButton               matlab.ui.control.Button
        ClearBlanksButton            matlab.ui.control.Button
        BlankButton                  matlab.ui.control.Button
        SetStopButton                matlab.ui.control.Button
        SetStartButton               matlab.ui.control.Button
        HalfGainButton               matlab.ui.control.Button
        DoubleGainButton             matlab.ui.control.Button
        ToggleFiducialPointButton    matlab.ui.control.Button
        ToggleQRSTSubtractionButton  matlab.ui.control.Button
        ECSAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % from loadfile
        pn
        pn1
        fname
        pname
        xx
        yy
        RecName
        t1 = 1;
        t2
        subflag = 0;
        fidflag = 1;
        ecggain = 1;
        blanklist = [];
        ecg
        ecgind
        ecgdur
        ecgh
        ecgsub
        med
        % from readepworkmate/main
        leadtxt
        ecglen
        fs
        %from fiducialcc2
        FID
        %from updatefile/plotpwrspect
        ecgtemp
        lineh
        % from detectbeats
        f
        ecgtemp1
        ecgf
        medf
     end
    
    properties (Access = private)
        icagui % Description
    end
    
    methods (Access = public)
        
        
        %%% Readepworkmate
        %from readepworkmate file edited on 12/21/2021
        
        function [ecg,pathname] = readepworkmate(app,pathname, filename)
            ClarisFactor = 78; % nv/LSB
            
            
            if nargin == 0
                [filename,pathname] = uigetfile('*.txt');
            end
            
            A = load([pathname,filename]);
            
            app.ecglen = length(A);
            
            ecg = zeros(15,app.ecglen);
            app.leadtxt = {'I','II','III','AVR','AVL','AVF','V1','V2','V3','V4','V5','V6','V7','V8','V9'};
            
            for i = 1:15
                if length(dir([pathname,char(app.leadtxt{i}),'.TXT']))==1
                    A = load([pathname,char(app.leadtxt{i}),'.TXT']);
                    if length(A) == app.ecglen
                        % ecg(i,:) = A'/1000;
                        ecg(i,:) = A'*ClarisFactor/1e6; % convert to mV
                    end
                end
            end
            
        end
        
        
        %%% Updatefig
        %from updatefig file
        
        function updatefig(app)
            
            hold(app.ECSAxes,'on')
            
            for i = 1:length(app.FID)
                if app.fidflag == 0
                    set(app.lineh(i),'visible','off');
                else
                    set(app.lineh(i),'visible','on');
                end
            end
            
            % explicitly initializing t1 in here to attempt to solve error
            % message (index in position 1 exceeds array bounds (line 120))
            %app.t1 = 1;
            
            
            if app.subflag == 0
                for i = 1:length(app.ecgind)
                    
                    app.ecgtemp = zeros(1,app.ecgdur);
                    
                    app.ecgtemp(app.t1:app.t2) = app.ecg(app.ecgind(i),app.t1:app.t2);
                    for j = 1:length(app.blanklist)/2
                        a = app.blanklist((j-1)*2+1);
                        b = app.blanklist(j*2);
                        %ecgtemp(a:b) = 0;  JB chnaged 12/6/2018
                        ea=app.ecgtemp(a); eb=app.ecgtemp(b);
                        for ii=a:b
                            app.ecgtemp(ii)=ea+(ii-a)*(eb-ea)/(b-a);
                        end
                    end
                    set(app.ecgh(i),'ydata',app.ecgtemp*app.ecggain+13-i);
                end
                
                %%% Updatefig is working when subflag = 0', but not when subflag = 1
                %%% It returns an error saying that index in position 1 exceeds array
                %%% bounds. Only difference in the if statement is the usage of ecgsub
                %%% instead of ecg in the second line inside the loop.
                %%% ecg has a size 15 x 140155, while ecgsub only has 12 x 140155.
                %%% Maybe it needs to be increased from 12 to 15?
            else
                for i = 1:length(app.ecgind)
                    app.ecgtemp = zeros(1,app.ecgdur);
                    app.ecgtemp(app.t1:app.t2) = app.ecgsub(app.ecgind(i),app.t1:app.t2);
                    for j = 1:length(app.blanklist)/2
                        a = app.blanklist((j-1)*2+1);
                        b = app.blanklist(j*2);
                        % ecgtemp(a:b) = 0; ;  JB chnaged 12/6/2018
                        ea=app.ecgtemp(a); eb=app.ecgtemp(b);
                        for ii=a:b
                            app.ecgtemp(ii)=ea+(ii-a)*(eb-ea)/(b-a);
                        end
                    end
                    set(app.ecgh(i),'ydata',app.ecgtemp*app.ecggain+13-i);
                end
            end
        end


        %%% Qrssub
        %from qrssub file
        
        function [ecgsub,med] = qrssub(app,ecg,FID)
            
            templ = 1200;
            
            l = length(ecg(1,:));
            clear med;
            ind = find((FID>201)&(FID<l-(templ-200)));
            
            [bh,ah] = butter(2,10/(2000/2),'high');
            
            
            for j = 1:size(ecg,1)
                temp1 = zeros(length(ind),templ);
                for i = 1:length(ind)
                    a = FID(ind(i)) - 200;
                    b = a + templ-1;
                    temp1(i,:) = ecg(j,a:b) - mean(ecg(j,a:b));
                end
                med(j,:) = median(temp1,1);
                med(j,:) = med(j,:) - mean(med(j,[1:20,(templ-19):templ]));
                
                ecgf(j,:) = filtfilt(bh,ah,ecg(j,:));
                medf(j,:) = filtfilt(bh,ah,med(j,:));
            end
            
            ecgsub = ecg;
            
            for j = 1:size(ecg,1)
                for i = 1:length(FID)
                    a = FID(i) - 200;
                    b = a + templ-1;
                    if a<1
                        aa = 2-a;
                        a = 1;
                    else
                        aa = 1;
                    end
                    if b > l
                        bb = templ - (b-l);
                        b = l;
                    else
                        bb = templ;
                    end
                    if (bb-aa)~=(b-a)
                        disp([aa bb a b]);
                    end
                    p = polyfit(medf(j,aa:bb)',ecgf(j,a:b)',1);
                    ecgsub(j,a:b) = ecgsub(j,a:b) - med(j,aa:bb)*p(1);
                end
                %% OJO JB 11/26/2-21  remove DC component ecgsub
                ecgsub(j,:)=ecgsub(j,:)-mean(ecgsub(j,:));
            end
        end


        %%% Fiducialcc2
        %from fiducialcc2 file
        
        function FID = fiducialcc2(app, ecg,med,perthresh)
            
            [N,L] = size(med);
            
            med = diff(med,[],2);
            
            %app.FID = [];
            
            app.ecgtemp = diff(ecg,[],2);
            clear cc;
            
            for i = 1:N
                cc(i,:) = conv(app.ecgtemp(i,:),fliplr(med(i,:)));
                ac(i,:) = conv(med(i,:),fliplr(med(i,:)));
            end
            
            cc = sum(cc,1);
            ac = sum(ac,1);
            [maxval,maxind] = max(ac);
            thresh = maxval*perthresh;
            
            ccd = diff(cc);
            dlen = length(ccd);
            peakind = find((ccd(1:dlen-1)>=0).*(ccd(2:dlen)<=0));%.*(cc(2:dlen)>maxval/3));
            peakind = peakind+1;
            peakamp = cc(peakind);
            
            % Searchback
            
            app.f = [];
            w = 500; % Blanking window;
            
            i = 0; %counter
            
            [maxval,maxind] = max(peakamp);
            
            while (maxval>thresh)
                i = i + 1;
                app.f(i) = peakind(maxind);
                newind = find((peakind<app.f(i)-w)|(peakind>app.f(i)+w));
                peakind = peakind(newind);
                peakamp = peakamp(newind);
                [maxval,maxind] = max(peakamp);
            end
            
            FID = app.f;
            
            FID = sort(FID);
            
            FID2 = [];
            for i = 1:length(FID)-1
                if abs(FID(i)-FID(i+1))>50
                    FID2 = [FID2 FID(i)];
                end
            end
            FID = FID2;
            
            
            if FID(1)<1
                FID  = FID(2:length(FID));
            end
        end
        
             
        %%% DetectBeats
      
        function detectbeats(app)
            [bh,ah] = butter(2,10/(2000/2),'high');
            app.ecgtemp1 = zeros(1,length(app.ecg(1,:)));
            clear ecgf;
            for i = 1:length(app.ecgind)
                app.ecgf(i,:) = filtfilt(bh,ah,app.ecg(app.ecgind(i),:));
                if (i>1)
                    app.ecgtemp1 = app.ecgtemp1 + app.ecgf(i,:).^2;
                end;
            end;
            app.ecgtemp1= sqrt(app.ecgtemp1);
            app.f = fiducial3(app, app.ecgtemp1);
            
            clear med;
            for j = 1:length(app.ecgind)
                temp1 = zeros(length(app.f)-2,100);
                for i = 2:length(app.f)-1
                    a = app.f(i) - 50;
                    b = a + 99;
                    temp1(i-1,:) = app.ecgf(j,a:b);
                end;
                
                app.medf(j,:) = median(temp1,1);
            end;
            
            app.FID = fiducialcc2(app, app.ecgf,app.medf,.3) - 50;
        end
        
        %%% Fiducial 3
        function FID = fiducial3(app, ecg)
            
            ecg = -diff(ecg);
            
            % Moving window integral
            
            b = ones(1,20)/20;
            a = 1;
            
            ecg = filter(b,a,ecg);
            
            % Searchback
            
            app.f = [];
            w = 250; % Blanking window;
            pts = length(ecg);
            
            
            [maxval,maxind] = max(ecg);
            ecgd = diff(ecg);
            dlen = length(ecgd);
            peakind = find((ecgd(1:dlen-1)>=0).*(ecgd(2:dlen)<=0).*(ecg(2:dlen)>maxval/5));
            peakind = peakind+1;
            peakamp = ecg(peakind);


            i = 0; %counter
            [maxval,maxind] = max(peakamp);
            maxsum = maxval;
            thresh = maxsum/3;
            while (maxval>thresh)
                i = i + 1;
                app.f(i) = peakind(maxind);
                newind = find((peakind<app.f(i)-w)|(peakind>app.f(i)+w));
                peakind = peakind(newind);
                peakamp = peakamp(newind);
                maxsum = maxsum + maxval;
                %thresh = maxsum/3/(i+1);
                [maxval,maxind] = max(peakamp);
            end;
            FID = sort(app.f) - 2;
            if FID(1)<1
                FID = FID(2:i);
            end
            
        end
        
       
        %%% The URL to the forum page is: https://www.mathworks.com/matlabcentral/answers/406342-app-designer-how-to-get-mouse-position-when-i-click-on-figure
        function [x, y] = selectDataPoints(~, ax)
            roi = drawpoint(ax);
            x = roi.Position(1);
            y = roi.Position(2);
            % This last line was added by TG - November 2021, to delete the
            % blue marker on the point
            delete(roi);
        end
             
        
        % return function
        function results = updateECGs(app)
            updatefig(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            %establishing leadtxt once again
            %leadtxt = {'I','II','III','AVR','AVL','AVF','V1','V2','V3','V4','V5','V6','V7','V8','V9'};
            
            %Hides the app until a file is selected
            app.AtrialECGUIFigure.Visible = 'off';
            
            %app.pn='G:\My Drive\UM\Projects\2018 Cardiology\Subjects\02 Clean Baselines V4-V9\';
            app.pn='.\';
            app.pn1 = pwd;
            
            % Selection of file
            [app.fname, app.pname] = uigetfile([app.pn '*.txt']);
            
            %shows the app once the file is selected
            app.AtrialECGUIFigure.Visible = 'on';
            % Recovers recording name to display on plotting title
            app.xx = strfind(app.pname,'\Pt');
            app.yy = strfind(app.pname, '\');
            M = length(app.yy);
            app.RecName = app.pname(app.yy(M-2)+1:app.yy(M)-1);
            
            fprintf('processing %s \n', app.pname);
            cd(app.pn1);
            app.ecg = readepworkmate(app,app.pname, app.fname);
            app.fs=2000; % from workmate
            %ecgaxesh = axes('units','normalized','position',[.05 .1 .9 .85]);
            %axes(ecgaxesh);
            %cla;
            
            app.ecgdur = length(app.ecg(1,:));
            app.t2 = app.ecgdur;
            [bp,ap] = butter(2,[.5 30]/(2000/2));
            
            app.ecgind = find(sum(app.ecg,2)~=0);
            
           
            cla(app.ECSAxes);
            %ecgaxesh = axes('units','normalized','position',[.05 .1 .9 .85]);
            
            for i = 1:length(app.ecgind)
                app.ecg(i,:) = filtfilt(bp,ap,app.ecg(i,:));
                app.ecgh(i) = plot(app.ECSAxes, [1:app.ecgdur]/2000,13-i+app.ecg(app.ecgind(i),:));
                hold(app.ECSAxes, 'on');
                text(app.ECSAxes,.1,13.5-i,app.leadtxt(app.ecgind(i)));
            end
            
            axis(app.ECSAxes,[0 app.ecgdur/2000 0 14]);
            
            %added app.ECSAxes to make sure it is in the proper figure
            title(app.ECSAxes, app.RecName);
            
            box off
            
            detectbeats(app);
            
            app.FID = app.FID(find((app.FID>2000)&(app.FID<(app.ecgdur-2000))));
            for i = 1:length(app.FID)
                app.lineh(i) = line(app.ECSAxes,[app.FID(i)/2000 app.FID(i)/2000],[0 14],'color',[1 0 0]);
            end;
            
            [app.ecgsub(app.ecgind,:),app.med] = qrssub(app, app.ecg(app.ecgind,:),app.FID);
            
            % A figure was being unwantedly created, so I just set it to be deleted
            delete(figure(1))
            
        end

        % Button pushed function: SetStartButton
        function SetStartButtonPushed(app, event)
            
            [gx, gy] = selectDataPoints(app, app.ECSAxes);
            app.t1 = round(gx*2000);
            
            %%% The difference between this function and the previous one
            %%% is that you have to select the point first and then press
            %%% the button, not the otehr way around.
            
            if app.t1< app.t2-4000
                if app.t1 < 1
                    app.t1 = 1;
                end
            end
            
            updatefig(app);
        end

        % Button pushed function: SetStopButton
        function SetStopButtonPushed(app, event)
            %from setstop.m
           
            [gx, gy] = selectDataPoints(app, app.ECSAxes);
            app.t2 = round(gx*2000);
            
            if app.t2 > app.t1+4000
                if app.t2 > app.ecgdur
                    app.t2 = app.ecgdur;
                end
            end
            
            updatefig(app);
        end

        % Button pushed function: ThreeQuartersGainButton
        function ThreeQuartersGainButtonPushed(app, event)
            %from callback function in panel.m
            app.ecggain = 0.75 * app.ecggain;
            app.KLabel.Text=['K:' num2str(app.ecggain)];
            updatefig(app);
        end

        % Button pushed function: HalfGainButton
        function HalfGainButtonPushed(app, event)
            %from callback function in panel.m
            app.ecggain = 0.5 * app.ecggain;
            app.KLabel.Text=['K:' num2str(app.ecggain)];
            updatefig(app);
        end

        % Button pushed function: OneAndHalfGainButton
        function OneAndHalfGainButtonPushed(app, event)
            %from callback function in panel.m
            app.ecggain = 1.5 * app.ecggain;
            app.KLabel.Text=['K:' num2str(app.ecggain)];
            updatefig(app);
        end

        % Button pushed function: DoubleGainButton
        function DoubleGainButtonPushed(app, event)
            %from callback function in panel.m
            app.ecggain = 2 * app.ecggain;
            app.KLabel.Text=['K:' num2str(app.ecggain)];
            updatefig(app);
        end

        % Button pushed function: BlankButton
        function BlankButtonPushed(app, event)
            %from blankecg.m
            
            %%% ginput doesn't work anymore
            %[gx,gy] = ginput(2);
            %gx = round(gx*2000);
            
            %             temp = app.ECSAxes.CurrentPoint(1,2)
            %             gx = temp(1,1);
            %             gx = round(gx*2000);
            
            [gx1, gy1] = selectDataPoints(app, app.ECSAxes);
            [gx2, gy2] = selectDataPoints(app, app.ECSAxes);
            gx1 = round(gx1*2000);
            gx2 = round(gx2 *2000);
            
            if gx1 < gx2
                app.blanklist = [app.blanklist gx1 gx2];
            else
                app.blanklist = [app.blanklist gx2 gx1];
            end
            
            updatefig(app);
        end

        % Button pushed function: ClearBlanksButton
        function ClearBlanksButtonPushed(app, event)
            %from callback function in the panel.m file
            app.blanklist = [];
            updatefig(app);
        end

        % Button pushed function: ToggleQRSTSubtractionButton
        function ToggleQRSTSubtractionButtonPushed(app, event)
            %from callback function in the panel.m file
            app.subflag = 1  - app.subflag;
            updatefig(app);
        end

        % Button pushed function: ToggleFiducialPointButton
        function ToggleFiducialPointButtonPushed(app, event)
            app.fidflag = 1 - app.fidflag;
            updatefig(app);
        end

        % Button pushed function: SaveECGsButton
        function SaveECGsButtonPushed(app, event)
            % saves the ECGs original and f waves along with the QRS
            % detection
            [fn,pn,indx] = uiputfile('*.mat','OutputFile');
            if fn~=0
                % stores
                StrucECG.RecName=app.RecName;
                StrucECG.fs=app.fs;
                StrucECG.ecg=app.ecg;          % originalecg
                StrucECG.ecgsub=app.ecgsub;    % fwave
                StrucECG.t1=app.t1;            % first sample
                StrucECG.t2=app.t2;            % last selected sample
                StrucECG.leadtxt=app.leadtxt;  % lead labels
                StrucECG.ecgind=app.ecgind;    % valid traces
                StrucECG.FID=app.FID;          % QRS Detection Points
                
                save([pn fn],'StrucECG')
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AtrialECGUIFigure = uifigure('Visible', 'off');
            app.AtrialECGUIFigure.Name = 'Atrial ECG';
            app.FileMenu = uimenu(app.AtrialECGUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenProcessedECGsMenu = uimenu(app.FileMenu);
            app.OpenProcessedECGsMenu.Text = 'Open Processed ECGs';
            app.SaveProcessedECGsMenu = uimenu(app.FileMenu);
            app.SaveProcessedECGsMenu.Text = 'Save Processed ECGs';
            app.ECSAxes = uiaxes(app.AtrialECGUIFigure);
            title(app.ECSAxes, 'ECGs')
            xlabel(app.ECSAxes, 'time(s)')
            ylabel(app.ECSAxes, 'Amplitude')
            zlabel(app.ECSAxes, 'Z')
            app.ToggleQRSTSubtractionButton = uibutton(app.AtrialECGUIFigure, 'push');
            app.ToggleQRSTSubtractionButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleQRSTSubtractionButtonPushed, true);
            app.ToggleQRSTSubtractionButton.Text = 'Toggle QRST Subtraction';
            app.ToggleFiducialPointButton = uibutton(app.AtrialECGUIFigure, 'push');
            app.ToggleFiducialPointButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleFiducialPointButtonPushed, true);
            app.ToggleFiducialPointButton.Text = 'Toggle Fiducial Point';
            app.DoubleGainButton = uibutton(app.AtrialECGUIFigure, 'push');
            app.DoubleGainButton.ButtonPushedFcn = createCallbackFcn(app, @DoubleGainButtonPushed, true);
            app.DoubleGainButton.Text = 'K x 2';
            app.HalfGainButton = uibutton(app.AtrialECGUIFigure, 'push');
            app.HalfGainButton.ButtonPushedFcn = createCallbackFcn(app, @HalfGainButtonPushed, true);
            app.HalfGainButton.Text = 'K x 0.5';
            app.SetStartButton = uibutton(app.AtrialECGUIFigure, 'push');
            app.SetStartButton.ButtonPushedFcn = createCallbackFcn(app, @SetStartButtonPushed, true);
            app.SetStartButton.Text = 'Set Start';
            app.SetStopButton = uibutton(app.AtrialECGUIFigure, 'push');
            app.SetStopButton.ButtonPushedFcn = createCallbackFcn(app, @SetStopButtonPushed, true);
            app.SetStopButton.Text = 'Set Stop';
            app.BlankButton = uibutton(app.AtrialECGUIFigure, 'push');
            app.BlankButton.ButtonPushedFcn = createCallbackFcn(app, @BlankButtonPushed, true);
            app.BlankButton.Text = 'Blank';
            app.ClearBlanksButton = uibutton(app.AtrialECGUIFigure, 'push');
            app.ClearBlanksButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBlanksButtonPushed, true);
            app.ClearBlanksButton.Text = 'Clear Blanks';
            app.SaveECGsButton = uibutton(app.AtrialECGUIFigure, 'push');
            app.SaveECGsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveECGsButtonPushed, true);
            app.SaveECGsButton.Text = 'Save ECGs';
            app.ThreeQuartersGainButton = uibutton(app.AtrialECGUIFigure, 'push');
            app.ThreeQuartersGainButton.ButtonPushedFcn = createCallbackFcn(app, @ThreeQuartersGainButtonPushed, true);
            app.ThreeQuartersGainButton.Text = 'K x 0.75';
            app.OneAndHalfGainButton = uibutton(app.AtrialECGUIFigure, 'push');
            app.OneAndHalfGainButton.ButtonPushedFcn = createCallbackFcn(app, @OneAndHalfGainButtonPushed, true);
            app.OneAndHalfGainButton.Text = 'K x 1.5';
            app.SaveFigsCheckBox = uicheckbox(app.AtrialECGUIFigure);
            app.SaveFigsCheckBox.Text = 'Save Figs';
            app.KLabel = uilabel(app.AtrialECGUIFigure);
            app.KLabel.Text = 'K=';
            % Show the figure after all components are created
            app.AtrialECGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AtrialECG_V1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AtrialECGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AtrialECGUIFigure)
        end
    end
end